classdef BipolarInverter_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BipolarInverterGUI              matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        WelcomeTab                      matlab.ui.container.Tab
        InverterLabel                   matlab.ui.control.Label
        TheorybehindBidirectionalInverterLabel_2  matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        TheorybehindBidirectionalInverterLabel  matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        DCACBidirectionalLabel          matlab.ui.control.Label
        TheorybehindBidirectionalInverterTab  matlab.ui.container.Tab
        BipolarInverterLabel_2          matlab.ui.control.Label
        CalculatethemodulatingratioPanel  matlab.ui.container.Panel
        ModulatingFrequencyEditField    matlab.ui.control.NumericEditField
        ModulatingFrequencyEditFieldLabel  matlab.ui.control.Label
        CarrierfrequencyEditField       matlab.ui.control.NumericEditField
        CarrierfrequencyEditFieldLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ModulatingratioEditField        matlab.ui.control.NumericEditField
        ModulatingratioEditFieldLabel   matlab.ui.control.Label
        Summary_3                       matlab.ui.control.TextArea
        Hyperlink3                      matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        ResourcesLabel                  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        Image3                          matlab.ui.control.Image
        Summary_2                       matlab.ui.control.TextArea
        Image2                          matlab.ui.control.Image
        ApplicationsLabel               matlab.ui.control.Label
        Summary                         matlab.ui.control.TextArea
        BipolarInverterLabel            matlab.ui.control.Label
        SimulationsTab                  matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        StatusUnknownLabel              matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ExportDataButton                matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        SimulationTimeSpinner           matlab.ui.control.Spinner
        SimulationTimeSpinnerLabel      matlab.ui.control.Label
        CompareSimulationsCheckBox      matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        LCFilterValuesPanel             matlab.ui.container.Panel
        LowPassFilterSwitch             matlab.ui.control.Switch
        LowPassFilterSwitchLabel        matlab.ui.control.Label
        CapacitorCEditField             matlab.ui.control.NumericEditField
        CapacitorCEditFieldLabel        matlab.ui.control.Label
        InductorHEditField              matlab.ui.control.NumericEditField
        InductorHEditFieldLabel         matlab.ui.control.Label
        InverterVoltagePanel            matlab.ui.container.Panel
        DCVoltageVEditField             matlab.ui.control.NumericEditField
        DCVoltageVEditFieldLabel        matlab.ui.control.Label
        LoadPanel                       matlab.ui.container.Panel
        ResistanceEditField             matlab.ui.control.NumericEditField
        ResistanceEditFieldLabel        matlab.ui.control.Label
        CarriersignalPanel              matlab.ui.container.Panel
        FrequencyHzEditField_2          matlab.ui.control.NumericEditField
        FrequencyHzEditField_2Label     matlab.ui.control.Label
        ModulatingSignalPanel           matlab.ui.container.Panel
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
    end

      properties (Access = private)
        yData % Simulation Output      
        % Simulation results
        currentRun = 0; % Initialise initial conditions
        previousRun = 0; % Initialise initial conditions      properties (Access = private)

        SimulationCounter=0;
        C_Switch;
        rnd_Modulating;
        rnd_Carrier;
        
        % Simulation results
        
                       
      end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
             app.BipolarInverterGUI.Name = 'Bipolar Inverter (Online Learning)';
             
             % Simulation counter
             app.SimulationCounter = 0;
             
             % Start Simulink to pay the one time cost of loading in the
             % libraries
             start_simulink;
             
             % Generating random value for modulating frequency
             a = 50;
             b = 100;
             r = round((b-a).*rand(1000,1) + a);
             app.rnd_Modulating = r(1,1);
             app.ModulatingFrequencyEditField.Value = app.rnd_Modulating;
             
             % Generating random value for carrier frequency 
             c = 1;
             d = 1000;
             rb = round((d-c).*rand(1000,1) + c);
             app.rnd_Carrier= rb(1,1);
             app.CarrierfrequencyEditField.Value = app.rnd_Carrier;
            
             % Disable all the simulation features 


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %Disable the push button and let the user know the application is still running
            app.StatusUnknownLabel.Text = 'Status: Simulation is running';
            app.StatusUnknownLabel.FontColor = '0.47,0.67,0.19';
            app.RunButton.Enable = 'off';
            app.Label.Text = '';
            app.Label.FontColor = '0.15,0.15,0.15';
            app.SimulationCounter = app.SimulationCounter +1;
            
            %Obtain  and store variable value  
            stopTimeStr = num2str(app.SimulationTimeSpinner.Value); % simulation time                     
            fm =app.FrequencyHzEditField.Value; % modulating frequency [Hz]
            Am = app.AmplitudeEditField.Value; % modulating amplitude
            XLf = app.InductorHEditField.Value; % Low pass filter user inductance [Hendrys]
            XCf = app.CapacitorCEditField.Value; % Low pass filter capacitance [Farads]
            RL = app.ResistanceEditField.Value; %Load resistance [ohms]
            Vs = app.DCVoltageVEditField.Value; % DC voltage 
            fkz = app.FrequencyHzEditField_2.Value; %Carrier frequency [Hz]
            C_switch =1;
            Lf=0.0000001;
            Cf=0.0000001;
            L_switch =0;
            
            % Low pass filter logic
            LPF = app.LowPassFilterSwitch.Value; 
            switch LPF
                case 'On'
                   Cf=XCf;
                   Lf = XLf; % Low pass filter inductance value to simulink
                case 'Off'
                   L_switch =1;
                   Cf=1000;
                   Lf= 1000;
                   C_switch =0;
            end
            
            % Set simulink model values using the user values
            model1 = "BipolarInverter";        
            in = Simulink.SimulationInput(model1);
            in = in.setVariable('C_switch',C_switch);
            in = in.setVariable('L_switch',L_switch);
            in = in.setVariable('fkz',fkz);
            in = in.setVariable('fm',fm);
            in = in.setVariable('Am',Am);
            in = in.setVariable('Lf',Lf);
            in = in.setVariable('Cf',Cf);
            in = in.setVariable('RL',RL);
            in = in.setVariable('Vs',Vs);
            in = in.setModelParameter('StopTime', stopTimeStr);
           
            out = sim(in);
            
            pause(1);
            
            %% Plot the simulink outputs to the user interface
            
            plot(app.UIAxes, out.yout{5}.Values.Time, out.yout{5}.Values.Data(:,1));
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, out.yout{5}.Values.Time, out.yout{5}.Values.Data(:,2));
            hold(app.UIAxes, 'off');
            legend(app.UIAxes,'Modulating Signal','Carrier Signal', 'Location', 'best');
            
            %Switch 1 and 2
            plot(app.UIAxes2, out.yout{3}.Values.Time, out.yout{3}.Values.Data);
            %yline(app.UIAxes2,'Color', 'r');
            legend(app.UIAxes2, 'Location', 'best');
            
            %Switch 3 and 4
            plot(app.UIAxes3_2, out.yout{4}.Values.Time, out.yout{4}.Values.Data);
           %yline(app.UIAxes3_2,'Color', 'r');
            legend(app.UIAxes3_2, 'Location', 'best');
            
            %DC source voltage
            plot(app.UIAxes3, out.yout{2}.Values.Time, out.yout{2}.Values.Data);
            %yline(app.UIAxes3,Vs,'Color', 'r');
            legend(app.UIAxes3, 'Location', 'best');
            
            %Inverter output voltage
            plot(app.UIAxes4, out.yout{1}.Values.Time, out.yout{1}.Values.Data);
            %yline(app.UIAxes4,'Color', 'r');
            legend(app.UIAxes4, 'Location', 'best');
            %previousRun = app.previousRun;
            
           % Log simulation run data
            app.previousRun = app.currentRun; % Setting the previous run to the original current run
            app.currentRun = out; % Updating the current run
           
           %Enable the user to run simulation
           app.CompareSimulationsCheckBox.Enable = 'on';
           app.StatusUnknownLabel.Text = 'Status: Simulation done running';
           app.StatusUnknownLabel.FontColor = '0.15,0.15,0.15';
           app.RunButton.Enable = 'on';
        end

        % Value changed function: CompareSimulationsCheckBox
        function CompareSimulationsCheckBoxValueChanged(app, event)
            
            if app.SimulationCounter <2  
                app.CompareSimulationsCheckBox.Value = 0;
                app.Label.Text = 'Run simulation twice first';
                app.CompareSimulationsCheckBox.Enable = 'off';
                value =0;
            else
                value = app.CompareSimulationsCheckBox.Value;
            end
            
            switch value
                case 0
                    app.Label.Text = 'Status: Comparing simulations off';
                    app.Label.FontColor = '0.00,0.00,0.00';
                    
                    %Plotting Modulating and Carrier signal
                     plot(app.UIAxes, app.currentRun.yout{5}.Values.Time, app.currentRun.yout{5}.Values.Data);
                    legend(app.UIAxes,'Mod1', 'Location', 'best');

                    %Plotting the Switching signal for switches 1 and 2
                    plot(app.UIAxes2, app.currentRun.yout{3}.Values.Time, app.currentRun.yout{3}.Values.Data);
                    legend(app.UIAxes2, 'Sim Current', 'Sim Previous', 'Location', 'best');
                    
                    
                    %Plotting the Switching signal for switches 3 and 4
                    plot(app.UIAxes3_2, app.currentRun.yout{4}.Values.Time, app.currentRun.yout{4}.Values.Data);
                    legend(app.UIAxes3_2, 'Sim Current', 'Sim Previous', 'Location', 'best');
                    
                    
                    %DC source voltage
                    plot(app.UIAxes3, app.currentRun.yout{2}.Values.Time, app.currentRun.yout{2}.Values.Data);
                    legend(app.UIAxes3, 'Sim Current', 'Sim Previous', 'Location', 'best');
                   
                    
                    %Inverter output voltage
                    plot(app.UIAxes4, app.currentRun.yout{1}.Values.Time, app.currentRun.yout{1}.Values.Data);
                    legend(app.UIAxes4,'Sim Current', 'Sim Previous', 'Location', 'best');
                    
                case 1
                    app.Label.Text = 'Status: Comparing simulations';
                    app.Label.FontColor = '0.00,0.00,0.00';
                    
                    %Plotting Modulating and Carrier signal
                    plot(app.UIAxes, app.currentRun.yout{5}.Values.Time, app.currentRun.yout{5}.Values.Data);
                    hold(app.UIAxes, "on");        
                    plot(app.UIAxes, app.previousRun.yout{5}.Values.Time, app.previousRun.yout{5}.Values.Data);
                    hold(app.UIAxes, 'off');
                    legend(app.UIAxes,'Mod1','C1', 'Location', 'best');

                    %Plotting the Switching signal for switches 1 and 2
                    plot(app.UIAxes2, app.currentRun.yout{3}.Values.Time, app.currentRun.yout{3}.Values.Data);
                    hold(app.UIAxes2, "on");
                    plot(app.UIAxes2, app.previousRun.yout{3}.Values.Time, app.previousRun.yout{3}.Values.Data, "Color", 'k');
                    legend(app.UIAxes2, 'Sim Current', 'Sim Previous', 'Location', 'best');
                    hold(app.UIAxes2, "off");
                    
                    %Plotting the Switching signal for switches 3 and 4
                    plot(app.UIAxes3_2, app.currentRun.yout{4}.Values.Time, app.currentRun.yout{4}.Values.Data);
                    hold(app.UIAxes3_2, "on");
                    plot(app.UIAxes3_2, app.previousRun.yout{4}.Values.Time, app.previousRun.yout{4}.Values.Data);
                    legend(app.UIAxes3_2, 'Sim Current', 'Sim Previous', 'Location', 'best');
                    hold(app.UIAxes3_2, "off");
                    
                    %DC source voltage
                    plot(app.UIAxes3, app.currentRun.yout{2}.Values.Time, app.currentRun.yout{2}.Values.Data);
                    hold(app.UIAxes3, "on");
                    plot(app.UIAxes3, app.previousRun.yout{2}.Values.Time, app.previousRun.yout{2}.Values.Data);
                    legend(app.UIAxes3, 'Sim Current', 'Sim Previous', 'Location', 'best');
                    hold(app.UIAxes3, "off");
                    
                    %Inverter output voltage
                    plot(app.UIAxes4, app.currentRun.yout{1}.Values.Time, app.currentRun.yout{1}.Values.Data);
                    hold(app.UIAxes4, "on");
                    plot(app.UIAxes4, app.previousRun.yout{1}.Values.Time, app.previousRun.yout{1}.Values.Data);
                    %yline(app.UIAxes4,'Color', 'r');
                    legend(app.UIAxes4,'Sim Current', 'Sim Previous', 'Location', 'best');
                    hold(app.UIAxes4, "off");
            end 
        end

        % Value changed function: LowPassFilterSwitch
        function LowPassFilterSwitchValueChanged(app, event)
            value = app.LowPassFilterSwitch.Value;
        switch value
                case 'On'
                   app.Label.FontColor = '0.85,0.32,0.11';
                   app.Label.Text ='LPF On: Press run button to implement Low pass filter';
                case 'Off'
                   app.Label.FontColor = '1.00,0.00,0.00';
                   app.Label.Text ='LPF Off: Press run button to implement Low pass filter';
        end
            
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            app.ExportDataButton.Enable = 'off';
            app.Label.FontColor = '0.00,0.00,0.00';
            app.Label.Text = "Exporting data function currently unavailable";
        end

        % Close request function: BipolarInverterGUI
        function BipolarInverterGUICloseRequest(app, event)
                      
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            
            val = app.rnd_Carrier/app.rnd_Modulating;
            val = round(val);
            %app.ModulatingratioEditField.Value = val;
            val_Compare = round(app.ModulatingratioEditField.Value);
            if val == val_Compare
                app.AmplitudeEditField.Editable = 'on';
                app.FrequencyHzEditField.Editable = 'on';
                app.FrequencyHzEditField_2.Editable = 'on';
                app.ResistanceEditField.Editable = 'on';
                app.DCVoltageVEditField.Editable = 'on';
                app.LowPassFilterSwitch.Enable = 'on';
                app.CapacitorCEditField.Editable = 'on';
                app.InductorHEditField.Editable = 'on';
                app.SimulationTimeSpinner.Editable = 'on';
                app.CompareSimulationsCheckBox.Enable = 'on';
                app.RunButton.Enable ='on';  
                
                app.FrequencyHzEditField_2.Value = app.rnd_Carrier;
                app.FrequencyHzEditField.Value = app.rnd_Modulating;
                
                app.Label_2.Text = "Correct! You have unlocked the simulations";
            else
                app.Label_2.Text = "Incorrect value! Try again";
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BipolarInverterGUI = uifigure('Visible', 'off');
            app.BipolarInverterGUI.Name = 'MATLAB App';
            app.BipolarInverterGUI.CloseRequestFcn = createCallbackFcn(app, @BipolarInverterGUICloseRequest, true);
            app.TabGroup = uitabgroup(app.BipolarInverterGUI);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.DCACBidirectionalLabel = uilabel(app.WelcomeTab);
            app.DCACBidirectionalLabel.Text = 'DC/AC Bi-directional';
            app.Image = uiimage(app.WelcomeTab);
            app.Image.ImageSource = 'UCT_Logo.jpg';
            app.Image_2 = uiimage(app.WelcomeTab);
            app.Image_2.ImageSource = 'elearning-tile.jpg';
            app.TheorybehindBidirectionalInverterLabel = uilabel(app.WelcomeTab);
            app.TheorybehindBidirectionalInverterLabel.Text = 'Theory behind Bidirectional Inverter';
            app.TextArea = uitextarea(app.WelcomeTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'The first tab can be used to learn about theory behind Boost Converters. This tab contains all the information needed to understand how a boost converter works, the relevant voltage and current graphs and how they look like, applications of buck converters and more. Students should follow the tabs in order and proceed to the next tab once all theoretical knowledge has been understood.'};
            app.TextArea_2 = uitextarea(app.WelcomeTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'The first tab can be used to learn about theory behind Boost Converters. This tab contains all the information needed to understand how a boost converter works, the relevant voltage and current graphs and how they look like, applications of buck converters and more. Students should follow the tabs in order and proceed to the next tab once all theoretical knowledge has been understood.'};
            app.TheorybehindBidirectionalInverterLabel_2 = uilabel(app.WelcomeTab);
            app.TheorybehindBidirectionalInverterLabel_2.Text = 'Theory behind Bidirectional Inverter';
            app.InverterLabel = uilabel(app.WelcomeTab);
            app.InverterLabel.Text = 'Inverter';
            app.TheorybehindBidirectionalInverterTab = uitab(app.TabGroup);
            app.TheorybehindBidirectionalInverterTab.Title = 'Theory behind Bidirectional Inverter';
            app.BipolarInverterLabel = uilabel(app.TheorybehindBidirectionalInverterTab);
            app.BipolarInverterLabel.Text = 'Bipolar Inverter';
            app.Summary = uitextarea(app.TheorybehindBidirectionalInverterTab);
            app.Summary.Editable = 'off';
            app.Summary.Value = {'A boost converter takes in voltage from a DC source and outputs a DC voltage with a higher value than the input voltage. The images below show the operation of a boost converter when the transistor is ''on'' and ''off'' as well as the voltage and current waveforms produced by the circuit.'};
            app.ApplicationsLabel = uilabel(app.TheorybehindBidirectionalInverterTab);
            app.ApplicationsLabel.Text = 'Applications';
            app.Image2 = uiimage(app.TheorybehindBidirectionalInverterTab);
            app.Image2.ImageSource = 'BipolarInverter.png';
            app.Summary_2 = uitextarea(app.TheorybehindBidirectionalInverterTab);
            app.Summary_2.Editable = 'off';
            app.Summary_2.Value = {'N.B Now, we will simulate the'; 'Modulation index'};
            app.Image3 = uiimage(app.TheorybehindBidirectionalInverterTab);
            app.Image3.ImageSource = 'schemeit-project.png';
            app.Hyperlink = uihyperlink(app.TheorybehindBidirectionalInverterTab);
            app.Hyperlink.URL = 'https://www.tntech.edu/engineering/pdf/cesr/ojo/asuri/Chapter2.pdf';
            app.Hyperlink.Text = {'PDF notes of Single phase inverters'; ''};
            app.ResourcesLabel = uilabel(app.TheorybehindBidirectionalInverterTab);
            app.ResourcesLabel.Text = 'Resources';
            app.Hyperlink2 = uihyperlink(app.TheorybehindBidirectionalInverterTab);
            app.Hyperlink2.URL = 'https://www.youtube.com/watch?v=iIqhAX0I7lI';
            app.Hyperlink2.Text = 'Video explanation of inverters';
            app.Hyperlink3 = uihyperlink(app.TheorybehindBidirectionalInverterTab);
            app.Hyperlink3.URL = 'https://industrial.panasonic.com/ww/ss/technical/b4';
            app.Hyperlink3.Text = 'Low pass filter notes';
            app.Summary_3 = uitextarea(app.TheorybehindBidirectionalInverterTab);
            app.Summary_3.Editable = 'off';
            app.Summary_3.Value = {'- Inverters can be used as an UPS-Uninterruptible power supplies'; '- These can be used as standalone inverters'; '- These can be used in solar power systems'; '- An inverter is the basic building block of an SMPS- switched mode power supply.'};
            app.CalculatethemodulatingratioPanel = uipanel(app.TheorybehindBidirectionalInverterTab);
            app.CalculatethemodulatingratioPanel.Title = 'Calculate the modulating ratio';
            app.ModulatingratioEditFieldLabel = uilabel(app.CalculatethemodulatingratioPanel);
            app.ModulatingratioEditFieldLabel.Text = 'Modulating ratio';
            app.ModulatingratioEditField = uieditfield(app.CalculatethemodulatingratioPanel, 'numeric');
            app.CalculateButton = uibutton(app.CalculatethemodulatingratioPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label_2 = uilabel(app.CalculatethemodulatingratioPanel);
            app.Label_2.Text = '';
            app.CarrierfrequencyEditFieldLabel = uilabel(app.CalculatethemodulatingratioPanel);
            app.CarrierfrequencyEditFieldLabel.Text = 'Carrier frequency';
            app.CarrierfrequencyEditField = uieditfield(app.CalculatethemodulatingratioPanel, 'numeric');
            app.CarrierfrequencyEditField.Editable = 'off';
            app.ModulatingFrequencyEditFieldLabel = uilabel(app.CalculatethemodulatingratioPanel);
            app.ModulatingFrequencyEditFieldLabel.Text = 'Modulating Frequency';
            app.ModulatingFrequencyEditField = uieditfield(app.CalculatethemodulatingratioPanel, 'numeric');
            app.ModulatingFrequencyEditField.Editable = 'off';
            app.BipolarInverterLabel_2 = uilabel(app.TheorybehindBidirectionalInverterTab);
            app.BipolarInverterLabel_2.Text = 'Bipolar Inverter';
            app.SimulationsTab = uitab(app.TabGroup);
            app.SimulationsTab.Title = ' Simulations';
            app.ModulatingSignalPanel = uipanel(app.SimulationsTab);
            app.ModulatingSignalPanel.Title = 'Modulating Signal';
            app.FrequencyHzEditFieldLabel = uilabel(app.ModulatingSignalPanel);
            app.FrequencyHzEditFieldLabel.Text = ' Frequency [Hz]';
            app.FrequencyHzEditField = uieditfield(app.ModulatingSignalPanel, 'numeric');
            app.FrequencyHzEditField.Editable = 'off';
            app.FrequencyHzEditField.Value = 50;
            app.AmplitudeEditFieldLabel = uilabel(app.ModulatingSignalPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.ModulatingSignalPanel, 'numeric');
            app.AmplitudeEditField.Editable = 'off';
            app.AmplitudeEditField.Value = 0.8;
            app.CarriersignalPanel = uipanel(app.SimulationsTab);
            app.CarriersignalPanel.Title = 'Carrier signal';
            app.FrequencyHzEditField_2Label = uilabel(app.CarriersignalPanel);
            app.FrequencyHzEditField_2Label.Text = 'Frequency [Hz]';
            app.FrequencyHzEditField_2 = uieditfield(app.CarriersignalPanel, 'numeric');
            app.FrequencyHzEditField_2.Editable = 'off';
            app.FrequencyHzEditField_2.Value = 400;
            app.LoadPanel = uipanel(app.SimulationsTab);
            app.LoadPanel.Title = 'Load';
            app.ResistanceEditFieldLabel = uilabel(app.LoadPanel);
            app.ResistanceEditFieldLabel.Text = 'Resistance [Ω]';
            app.ResistanceEditField = uieditfield(app.LoadPanel, 'numeric');
            app.ResistanceEditField.Editable = 'off';
            app.ResistanceEditField.Value = 100;
            app.InverterVoltagePanel = uipanel(app.SimulationsTab);
            app.InverterVoltagePanel.Title = 'Inverter Voltage';
            app.DCVoltageVEditFieldLabel = uilabel(app.InverterVoltagePanel);
            app.DCVoltageVEditFieldLabel.Text = 'DC Voltage [V]';
            app.DCVoltageVEditField = uieditfield(app.InverterVoltagePanel, 'numeric');
            app.DCVoltageVEditField.Editable = 'off';
            app.DCVoltageVEditField.Value = 100;
            app.LCFilterValuesPanel = uipanel(app.SimulationsTab);
            app.LCFilterValuesPanel.Title = 'LC Filter Values';
            app.InductorHEditFieldLabel = uilabel(app.LCFilterValuesPanel);
            app.InductorHEditFieldLabel.Text = 'Inductor [H]';
            app.InductorHEditField = uieditfield(app.LCFilterValuesPanel, 'numeric');
            app.InductorHEditField.Editable = 'off';
            app.InductorHEditField.Value = 0.0045;
            app.CapacitorCEditFieldLabel = uilabel(app.LCFilterValuesPanel);
            app.CapacitorCEditFieldLabel.Text = 'Capacitor [C]';
            app.CapacitorCEditField = uieditfield(app.LCFilterValuesPanel, 'numeric');
            app.CapacitorCEditField.Editable = 'off';
            app.CapacitorCEditField.Value = 0.09;
            app.LowPassFilterSwitchLabel = uilabel(app.LCFilterValuesPanel);
            app.LowPassFilterSwitchLabel.Enable = 'off';
            app.LowPassFilterSwitchLabel.Text = 'Low Pass Filter';
            app.LowPassFilterSwitch = uiswitch(app.LCFilterValuesPanel, 'slider');
            app.LowPassFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @LowPassFilterSwitchValueChanged, true);
            app.LowPassFilterSwitch.Enable = 'off';
            app.LowPassFilterSwitch.Value = 'On';
            app.RunButton = uibutton(app.SimulationsTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.CompareSimulationsCheckBox = uicheckbox(app.SimulationsTab);
            app.CompareSimulationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompareSimulationsCheckBoxValueChanged, true);
            app.CompareSimulationsCheckBox.Enable = 'off';
            app.CompareSimulationsCheckBox.Text = 'Compare Simulations';
            app.SimulationTimeSpinnerLabel = uilabel(app.SimulationsTab);
            app.SimulationTimeSpinnerLabel.Text = 'Simulation Time';
            app.SimulationTimeSpinner = uispinner(app.SimulationsTab);
            app.SimulationTimeSpinner.Editable = 'off';
            app.SimulationTimeSpinner.Value = 0.1;
            app.Panel = uipanel(app.SimulationsTab);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Carrier and modulating signal')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Panel);
            title(app.UIAxes2, 'Switch S1 and S2 input')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.Panel);
            title(app.UIAxes4, 'Inverter output')
            xlabel(app.UIAxes4, 'Time (s)')
            ylabel(app.UIAxes4, 'Voltage (V)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3_2 = uiaxes(app.Panel);
            title(app.UIAxes3_2, 'Switch S3 and S4 input')
            xlabel(app.UIAxes3_2, 'Time (s)')
            ylabel(app.UIAxes3_2, 'Amplitude')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3 = uiaxes(app.Panel);
            title(app.UIAxes3, 'DC input Voltage')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Voltage (V)')
            zlabel(app.UIAxes3, 'Z')
            app.ExportDataButton = uibutton(app.SimulationsTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.Panel_2 = uipanel(app.SimulationsTab);
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '';
            app.StatusUnknownLabel = uilabel(app.Panel_2);
            app.StatusUnknownLabel.Text = 'Status: Unknown';
            % Show the figure after all components are created
            app.BipolarInverterGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BipolarInverter_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BipolarInverterGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BipolarInverterGUI)
        end
    end
end