classdef UnipolarInverter_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DCVoltageVEditField          matlab.ui.control.NumericEditField
        DCVoltageVEditFieldLabel     matlab.ui.control.Label
        CarriersignalPanel           matlab.ui.container.Panel
        FrequencyHzEditField_2       matlab.ui.control.NumericEditField
        FrequencyHzEditField_2Label  matlab.ui.control.Label
        InverterVoltagePanel         matlab.ui.container.Panel
        LoadPanel                    matlab.ui.container.Panel
        ResistanceEditField          matlab.ui.control.NumericEditField
        ResistanceEditFieldLabel     matlab.ui.control.Label
        LCFilterValuesPanel          matlab.ui.container.Panel
        LPFSwitch                    matlab.ui.control.Switch
        LPFSwitchLabel               matlab.ui.control.Label
        CapacitorCEditField          matlab.ui.control.NumericEditField
        CapacitorCEditFieldLabel     matlab.ui.control.Label
        InductorHEditField           matlab.ui.control.NumericEditField
        InductorHEditFieldLabel      matlab.ui.control.Label
        ModulatingSignalPanel        matlab.ui.container.Panel
        AmplitudeEditField           matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        FrequencyHzEditField         matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel    matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: LPFSwitch, RunButton
        function RunButtonPushed(app, event)
            
                                
            %C_switch = app.LPFSwitch.Value;
            
            fm =app.FrequencyHzEditField.Value; %Hz
            Am = app.AmplitudeEditField.Value;
            
            Lf = app.InductorHEditField.Value; %Hendrys
            Cf = app.CapacitorCEditField.Value; % Farads
         
            RL = app.ResistanceEditField.Value; %ohms
            
            Vs = app.DCVoltageVEditField.Value; %Volts 
            fc = app.FrequencyHzEditField_2.Value;
            
            model1 = "UnipolarInverter";
            open_system(model1);
            start_simulink
            in = Simulink.SimulationInput(model1);
            %in = in.setVariable('C_switch',C_switch);
            in = in.setVariable('fc',fc);
            in = in.setVariable('fm',fm);
            in = in.setVariable('Am',Am);
            in = in.setVariable('Lf',Lf);
            in = in.setVariable('Cf',Cf);
            in = in.setVariable('RL',RL);
            in = in.setVariable('Vs',Vs);
           
            out = sim(in);
            
            pause(1);
            
           
            plot(app.UIAxes, out.yout{5}.Values.Time, out.yout{5}.Values.Data);
            %yline(app.UIAxes,'Color', 'r');
            legend(app.UIAxes, 'Location', 'best');
           pause(1.1);
           %Switch 1 and 2
            plot(app.UIAxes2, out.yout{3}.Values.Time, out.yout{3}.Values.Data);
            %yline(app.UIAxes2,'Color', 'r');
            legend(app.UIAxes2, 'Location', 'best');
            
            %Switch 3 and 4
            plot(app.UIAxes3_2, out.yout{4}.Values.Time, out.yout{4}.Values.Data);
           %yline(app.UIAxes3_2,'Color', 'r');
            legend(app.UIAxes3_2, 'Location', 'best');
            
            %DC source voltage
            plot(app.UIAxes3, out.yout{2}.Values.Time, out.yout{2}.Values.Data);
            %yline(app.UIAxes3,Vs,'Color', 'r');
            legend(app.UIAxes3, 'Location', 'best');
            
            %Inverter output voltage
            plot(app.UIAxes4, out.yout{1}.Values.Time, out.yout{1}.Values.Data);
            %yline(app.UIAxes4,'Color', 'r');
            legend(app.UIAxes4, 'Location', 'best');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Carrier and modulating signal')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Switch S1 and S2 input')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'DC input Voltage')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Voltage (V)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Switch S3 and S4 input')
            xlabel(app.UIAxes3_2, 'Time (s)')
            ylabel(app.UIAxes3_2, 'Amplitude')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Inverter output')
            xlabel(app.UIAxes4, 'Time (s)')
            ylabel(app.UIAxes4, 'Voltage (V)')
            zlabel(app.UIAxes4, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ModulatingSignalPanel = uipanel(app.UIFigure);
            app.ModulatingSignalPanel.Title = 'Modulating Signal';
            app.FrequencyHzEditFieldLabel = uilabel(app.ModulatingSignalPanel);
            app.FrequencyHzEditFieldLabel.Text = ' Frequency [Hz]';
            app.FrequencyHzEditField = uieditfield(app.ModulatingSignalPanel, 'numeric');
            app.FrequencyHzEditField.Value = 50;
            app.AmplitudeEditFieldLabel = uilabel(app.ModulatingSignalPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.ModulatingSignalPanel, 'numeric');
            app.AmplitudeEditField.Value = 0.8;
            app.LCFilterValuesPanel = uipanel(app.UIFigure);
            app.LCFilterValuesPanel.Title = 'LC Filter Values';
            app.InductorHEditFieldLabel = uilabel(app.LCFilterValuesPanel);
            app.InductorHEditFieldLabel.Text = 'Inductor [H]';
            app.InductorHEditField = uieditfield(app.LCFilterValuesPanel, 'numeric');
            app.InductorHEditField.Value = 0.0014;
            app.CapacitorCEditFieldLabel = uilabel(app.LCFilterValuesPanel);
            app.CapacitorCEditFieldLabel.Text = 'Capacitor [C]';
            app.CapacitorCEditField = uieditfield(app.LCFilterValuesPanel, 'numeric');
            app.CapacitorCEditField.Value = 1.6e-09;
            app.LPFSwitchLabel = uilabel(app.LCFilterValuesPanel);
            app.LPFSwitchLabel.Text = 'LPF';
            app.LPFSwitch = uiswitch(app.LCFilterValuesPanel, 'slider');
            app.LPFSwitch.Items = {'0', '1'};
            app.LPFSwitch.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.LPFSwitch.Value = '0';
            app.LoadPanel = uipanel(app.UIFigure);
            app.LoadPanel.Title = 'Load';
            app.ResistanceEditFieldLabel = uilabel(app.LoadPanel);
            app.ResistanceEditFieldLabel.Text = 'Resistance [Ω]';
            app.ResistanceEditField = uieditfield(app.LoadPanel, 'numeric');
            app.ResistanceEditField.Value = 100;
            app.InverterVoltagePanel = uipanel(app.UIFigure);
            app.InverterVoltagePanel.Title = 'Inverter Voltage';
            app.CarriersignalPanel = uipanel(app.UIFigure);
            app.CarriersignalPanel.Title = 'Carrier signal';
            app.FrequencyHzEditField_2Label = uilabel(app.CarriersignalPanel);
            app.FrequencyHzEditField_2Label.Text = 'Frequency [Hz]';
            app.FrequencyHzEditField_2 = uieditfield(app.CarriersignalPanel, 'numeric');
            app.FrequencyHzEditField_2.Value = 400;
            app.DCVoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.DCVoltageVEditFieldLabel.Text = 'DC Voltage [V]';
            app.DCVoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.DCVoltageVEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UnipolarInverter_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end